classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConnectLabel                  matlab.ui.control.Label
        ClearLogButton                matlab.ui.control.StateButton
        SendButton                    matlab.ui.control.StateButton
        DisconnectButton              matlab.ui.control.StateButton
        LogTextArea                   matlab.ui.control.TextArea
        SYSTEMLOGTextAreaLabel        matlab.ui.control.Label
        TypeEditField                 matlab.ui.control.NumericEditField
        TYPEHEREEditFieldLabel        matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        DeviceListBox                 matlab.ui.control.ListBox
        AVAILABLEDEVICESListBoxLabel  matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.StateButton
        RefreshButton                 matlab.ui.control.StateButton
    end


    properties (Access = private)
        bt 
        btDeviceName = 'AGA08';
    end
    
    methods (Access = private)
        
        function dataReceivedCallback(app, src, ~)
           try
               if ~isvalid(src) || src.Status ~= "open"
                   app.LogTextArea.Value = [app.LogTextArea.Value; 'Warning: Data callback triggered on closed port.'];
                   return;
               end
               
               data = readline(src); 
               data = strtrim(data);

               if ~isempty(data)
                   currentLog = app.LogTextArea.Value;
                   if numel(currentLog) > 50
                       currentLog = currentLog(end-49:end); 
                   end
                   timestamp = datetime("now");
                   app.LogTextArea.Value = [currentLog; [timestamp, ' - RX: ', data]];
               end

            catch ME
                app.LogTextArea.Value = [app.LogTextArea.Value; 'ERROR reading data.'];
                if ~isempty(app.bt) && isvalid(app.bt)
                    flush(app.bt, "input");
                end
            end
        end

        function cleanupBluetooth(app)
            
            if ~isempty(app.bt) && isvalid(app.bt)
                try
                    delete(app.bt); 
                    app.bt = [];
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'Serial connection cleaned up.'];
                    
                catch ME
                    app.LogTextArea.Value = ['Error during serial cleanup: ', ME.message];
                end
            end
            app.LogTextArea.Value = [app.LogTextArea.Value; 'Cleanup completed.'];
        end

        function success = connectSystemBluetooth(app)
            success = false;
            deviceName = app.btDeviceName;

            blueutil_path = '/usr/local/bin/blueutil';

            if ~isfile(blueutil_path)
                app.LogTextArea.Value = [app.LogTextArea.Value; 'ERROR: blueutil not found.'];
                return;
            end
          
            app.LogTextArea.Value = [app.LogTextArea.Value; ['System: Attempting blueutil --connect "', deviceName, '"']];
            
            [status, result] = system([blueutil_path, ' --connect "', deviceName, '"']);

            if status == 0
                app.LogTextArea.Value = [app.LogTextArea.Value; 'System: Connection command executed. Waiting for link status...'];
                pause(2);
                success = true;
            else
                app.LogTextArea.Value = [app.LogTextArea.Value; ['System: blueutil connect failed. Error:', result]];
            end
        end

        function isConnected = checkSystemConnectionStatus(app)
            % Checks the live connection status using blueutil
            isConnected = false;
            deviceName = app.btDeviceName;
            blueutil_path = '/usr/local/bin/blueutil';
            
            if ~isfile(blueutil_path)
                return;
            end

            cmd = sprintf('"%s" --is-connected "%s"', blueutil_path, deviceName);
            [status, ~] = system(cmd);

            if status == 0
                isConnected = true;
            end
        end

        function success = waitForPortFile(app, portName, timeout)
            app.LogTextArea.Value = [app.LogTextArea.Value; ['System: Waiting for port file ', portName]];
            success = false;
            tic;
            while toc < timeout
                allPorts = serialportlist("available");
                if any(strcmp(allPorts, portName))
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'System: Port file found.'];
                    success = true;
                    return;
                end
                pause(0.5); 
            end
            app.LogTextArea.Value = [app.LogTextArea.Value; 'System: Port file never appeared.'];
        end

        function success = disconnectSystemBluetooth(app)
            success = false;
            deviceName = app.btDeviceName;
            
            blueutil_path = '/usr/local/bin/blueutil';

            if ~isfile(blueutil_path)
                return;
            end

            [status, ~] = system([blueutil_path, ' --disconnect "', deviceName, '"']);

            if status == 0
                success = true;
            end
        end

        function updateDeviceList(app)
            try
                allPorts = serialportlist("available");
                cuPorts = allPorts(startsWith(allPorts, '/dev/tty.'));
                
                if isempty(cuPorts)
                    app.DeviceListBox.Items = {'No /dev/tty. ports found'};
                else
                    app.DeviceListBox.Items = cuPorts;
                    if any(strcmp(cuPorts, app.btDeviceName))
                         app.DeviceListBox.Value = app.btSerialPort;
                    else
                         app.DeviceListBox.Value = cuPorts{1};
                    end
                end
            catch ME
                app.DeviceListBox.Items = {'Scan Error'};
                app.LogTextArea.Value = [app.LogTextArea.Value; ['Error updating list: ', ME.message]];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusLamp.Color = [1 0 0]; 
            app.ConnectLabel.Text = 'DISCONNECTED'; 
            app.LogTextArea.Value = {'Welcome to Bluetooth Terminal. Please press REFRESH to begin.'};
        end

        % Value changed function: ClearLogButton
        function ClearLogButtonValueChanged(app, event)
            if app.ClearLogButton.Value
                app.ClearLogButton.Value = false;
                app.LogTextArea.Value = {'Log cleared.'};
            end
        end

        % Value changed function: RefreshButton
        function RefreshButtonValueChanged(app, event)
            if app.RefreshButton.Value 
                app.RefreshButton.Value = false;
                app.LogTextArea.Value = [app.LogTextArea.Value; '--- Starting Serial Port Scan... ---'];
                
                try
                    allPorts = serialportlist("available");
                    
                    if isempty(allPorts)
                        app.DeviceListBox.Items = {'No COM ports found'};
                        app.LogTextArea.Value = [app.LogTextArea.Value; 'No active COM ports found.'];
                    else
                        cuPorts = allPorts(startsWith(allPorts, '/dev/tty.'));

                        if isempty(cuPorts)
                            app.DeviceListBox.Items = {'No /dev/tty. ports found'};
                        else
                            app.DeviceListBox.Items = cuPorts;
                            app.LogTextArea.Value = [app.LogTextArea.Value; ['Found ', num2str(numel(cuPorts)), ' Communication ports.']];
                        end
                    end
                    
                catch ME
                    app.DeviceListBox.Items = {'Scan Error'};
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'ERROR during scan'];
                end
            end
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            if app.ConnectButton.Value
                comPort = app.DeviceListBox.Value;

                if isempty(comPort) || iscell(comPort)
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'ERROR: Please select a valid COM port first.'];
                    app.ConnectButton.Value = false;
                    return;
                end

                app.LogTextArea.Value = [app.LogTextArea.Value; ['Attempting FULL connection to ', comPort]];
                app.ConnectButton.Text = 'Connecting...';

                try
                    app.cleanupBluetooth();
                    app.bt = serialport(comPort, 115200); 
                    app.bt.Timeout = 10;

                    app.LogTextArea.Value = [app.LogTextArea.Value; 'System: Forcing OS link...'];
                    if ~app.connectSystemBluetooth()
                       error('System link initiation failed (blueutil).');
                    end

                    pause(4);
                    
                    configureCallback(app.bt, "terminator", @app.dataReceivedCallback);

                    app.StatusLamp.Color = [0 1 0];
                    app.ConnectLabel.Text = 'CONNECTED';
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'FULL LINK ESTABLISHED.'];
                    app.ConnectButton.Text = 'Connected';

                catch ME
                    app.StatusLamp.Color = [1 0 0]; 
                    app.ConnectLabel.Text = 'DISCONNECTED';
                    app.LogTextArea.Value = [app.LogTextArea.Value; ['CONNECTION FAILED: ', ME.message]];
                    app.ConnectButton.Value = false;
                    app.ConnectButton.Text = 'CONNECT';
               
                    app.cleanupBluetooth(); 
                    app.disconnectSystemBluetooth();
                end
            end
        end

        % Value changed function: DisconnectButton
        function DisconnectButtonValueChanged(app, event)
         if app.DisconnectButton.Value 
                app.DisconnectButton.Value = false;
                
               try
                   app.cleanupBluetooth();
                   app.disconnectSystemBluetooth();

                   app.StatusLamp.Color = [1 0 0];
                   app.ConnectLabel.Text = 'DISCONNECTED';
                   app.ConnectButton.Value = false;
                   app.ConnectButton.Text = 'CONNECT';
                   app.LogTextArea.Value = [app.LogTextArea.Value; ' FULLY DISCONNECTED (OS link terminated).'];

               catch ME
                   app.LogTextArea.Value = [app.LogTextArea.Value; ['ERROR during disconnect: ', ME.message]];
               end
          end
        end

        % Value changed function: SendButton
        function SendButtonValueChanged(app, event)
            if app.SendButton.Value 
                app.SendButton.Value = false; 
                
                if ~isempty(app.bt) && isvalid(app.bt) && app.bt.Status == "open"
                    
                    dataToSend = num2str(app.TypeEditField.Value);
                    
                    try
                        writeline(app.bt, dataToSend); 
                        app.LogTextArea.Value = [app.LogTextArea.Value; ['TX: ', dataToSend]];
                        
                    catch ME
                        app.LogTextArea.Value = [app.LogTextArea.Value; 'ERROR sending data.'];
                    end
                else
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'ERROR: Not connected. Press CONNECT first.'];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RefreshButton = uibutton(app.UIFigure, 'state');
            app.RefreshButton.ValueChangedFcn = createCallbackFcn(app, @RefreshButtonValueChanged, true);
            app.RefreshButton.Text = 'REFRESH';
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'CONNECT';
            app.AVAILABLEDEVICESListBoxLabel = uilabel(app.UIFigure);
            app.AVAILABLEDEVICESListBoxLabel.Text = 'AVAILABLE DEVICES';
            app.DeviceListBox = uilistbox(app.UIFigure);
            app.StatusLamp = uilamp(app.UIFigure);
            app.TYPEHEREEditFieldLabel = uilabel(app.UIFigure);
            app.TYPEHEREEditFieldLabel.Text = 'TYPE  HERE';
            app.TypeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SYSTEMLOGTextAreaLabel = uilabel(app.UIFigure);
            app.SYSTEMLOGTextAreaLabel.Text = 'SYSTEM LOG';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.DisconnectButton = uibutton(app.UIFigure, 'state');
            app.DisconnectButton.ValueChangedFcn = createCallbackFcn(app, @DisconnectButtonValueChanged, true);
            app.DisconnectButton.Text = 'DISCONNECT';
            app.SendButton = uibutton(app.UIFigure, 'state');
            app.SendButton.ValueChangedFcn = createCallbackFcn(app, @SendButtonValueChanged, true);
            app.SendButton.Text = 'SEND';
            app.ClearLogButton = uibutton(app.UIFigure, 'state');
            app.ClearLogButton.ValueChangedFcn = createCallbackFcn(app, @ClearLogButtonValueChanged, true);
            app.ClearLogButton.Text = 'CLEAR LOG';
            app.ConnectLabel = uilabel(app.UIFigure);
            app.ConnectLabel.Text = 'CONNECTED';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end